classdef GUIcontrollerCa_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PropLabel            matlab.ui.control.Label
        SmoothlengthLabel    matlab.ui.control.Label
        Point_error          matlab.ui.control.Label
        SavetracesCheckBox   matlab.ui.control.CheckBox
        direc                matlab.ui.control.Label
        directory            matlab.ui.control.Label
        slider_smoothlength  matlab.ui.control.Slider
        slider_prop          matlab.ui.control.Slider
        ref_type             matlab.ui.control.DropDown
        finish               matlab.ui.control.Button
        text7                matlab.ui.control.Label
        text6                matlab.ui.control.Label
        alldone              matlab.ui.control.Label
        prevbut              matlab.ui.control.Button
        nextbut              matlab.ui.control.Button
        pushbutton_next      matlab.ui.control.Button
        pushbutton_treatall  matlab.ui.control.Button
        axes_vecfield        matlab.ui.control.UIAxes
        axes_signal          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        curdir % Description
        savedir
        list
        num
        PixelSize
        SamplingFrequency
        FileName
        mask
        results_pathname
        PSCa
        im1
        smoothlength
        prop
    end
    
    methods (Access = public)

        function app = loadfiles(app)
            app.direc.Text = [num2str(app.num) '/' num2str(length(app.list))];
            app.FileName = app.list{app.num};
            mask = load([app.savedir app.FileName(1:end-4) '_mask.mat']);
            app.mask=mask.mask;
            app.results_pathname=[app.savedir, app.FileName(1:end-4),'.xlsx'];
        end
        
        function app = loadPS(app)
            app.PSCa = PeriodicSignalsCaModel(app.FileName,app.mask,app.curdir,'PixelSize',app.PixelSize,'SamplingFrequency',app.SamplingFrequency);
            info=imfinfo([app.curdir, app.FileName]);
            app.PSCa.numim=length(info);
            app.PSCa.determineCenter;
            app.PSCa.MeasureIntensity;
            app.PSCa.FourierAnalysis;
        end

        function app = load_images(app)
            filename = [app.curdir app.list{app.num}];
            im = tiffreadVolume(filename);
            app.slider_smoothlength.Limits = [0 min([100,size(im,3)])];
            app.slider_smoothlength.MajorTicks = int8(0:10:app.slider_smoothlength.Limits(end));
            app.slider_smoothlength.MinorTicks = int8(0:1:app.slider_smoothlength.Limits(end));

            im1 = double(im(:,:,1));
            im1=im1(2:end,1:end);
            %im1(:,:) = im1(:,:) - mean2(im1(:,:));
            app.im1 = im1;
        end

        function app = plot_boxes(app)
            imshow(app.im1,[],'parent',app.axes_vecfield);
            hold(app.axes_vecfield, 'on')
            map=jet(length(app.PSCa.vecr));cc=1;
            for r=app.PSCa.vecr
                dtheta=app.PSCa.Box/r;
                for theta=0:dtheta:2*pi
                    cbox=[app.PSCa.z(2)+r*cos(theta) app.PSCa.z(1)+r*sin(theta)];
                    posrect=[cbox(1)-app.PSCa.Box/2 cbox(2)-app.PSCa.Box/2 app.PSCa.Box app.PSCa.Box];
                    if cc==1
                        h=rectangle('Position',posrect,'parent',app.axes_vecfield);
                        h.EdgeColor=map(cc,:);
                    elseif cc==length(app.PSCa.vecr)
                        h=rectangle('Position',posrect,'parent',app.axes_vecfield);
                        h.EdgeColor=map(cc,:);
                    end
                end
                cc=cc+1;
            end
            hold(app.axes_vecfield, 'off')
        end

        function app = plot_PeaksValleySignal(app)
            app.PSCa.GetCaractPeak('Smoothlength',app.slider_smoothlength.Value,'prop',app.slider_prop.Value);
            pos=app.PSCa.Signal.posper(:,:,1);
            M=app.PSCa.Signal.Mper(:,:,1);

            plot(app.PSCa.Time,app.PSCa.Global1,'parent',app.axes_signal)
            hold(app.axes_signal, 'on')
            plot(app.PSCa.Signal.xmc,app.PSCa.Signal.mmvg,'+c','parent',app.axes_signal)
            plot(app.PSCa.Signal.posM,app.PSCa.Signal.M,'+k','parent',app.axes_signal)
            plot(app.PSCa.Signal.xmr,app.PSCa.Signal.mmvd,'+g','parent',app.axes_signal) 
            plot(pos(:),M(:),'+m','linewidth',2,'parent',app.axes_signal)
            xlabel('Time (s)','parent',app.axes_signal)
            ylabel('Displacement (\mum)','parent',app.axes_signal)
            app.axes_signal.XTick = 0:min(1,max(app.PSCa.Time)):max(app.PSCa.Time);
            app.axes_signal.YTick = 0:min(0.5,max(app.PSCa.Global1)):max(app.PSCa.Global1);
            app.axes_signal.XTickLabel = 0:min(1,max(app.PSCa.Time)):max(app.PSCa.Time);
            app.axes_signal.YTickLabel = 0:min(0.5,max(app.PSCa.Global1)):max(app.PSCa.Global1);

                hold(app.axes_signal, 'off')
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %%Mettre ce que doit faire le code au démarrage
            
            %set(app.figure1,'toolbar','figure');
            
            p = inputParser;
            addRequired(p, 'Directory', @isstr);
            addParameter(p, 'SaveDir', pwd, @isstr);
            addParameter(p, 'PixelSize', 1);
            addParameter(p, 'SamplingFrequency', 1);
            
            parse(p, varargin{:});
            
            app.curdir = p.Results.Directory;
            app.num=1;
            app.savedir = p.Results.SaveDir;
            app.list = listTiffs(app.curdir);
            app.PixelSize = p.Results.PixelSize;
            app.SamplingFrequency = p.Results.SamplingFrequency;

            filesep = app.curdir(end);
            pos = strfind(app.curdir, filesep);
            app.directory.Text = num2str(app.curdir(pos(end-2)+1:pos(end-1)-1));
            app=loadfiles(app);

            set(app.Point_error,'Visible','off')
            set(app.finish,'Visible','off')
            set(app.alldone,'Visible','off')

            app = load_images(app);

            app.slider_smoothlength.Value = min([10, app.slider_smoothlength.MajorTicks(end)]);
            app.slider_prop.Value = 0.1;
            app = loadPS(app);
            app = plot_boxes(app);

            app = plot_PeaksValleySignal(app);
           
            
        end

        % Value changed function: ref_type
        function ref_typeValueChanged(app, event)
            app.ref_type_val = get(app.ref_type,'Value');
            app.ref_type_val = app.ref_type_val;
            app = loadPS(app);
            app = plot_boxes(app);
            app = plot_PeaksValleySignal(app);
        end

        % Value changed function: slider_smoothlength
        function slider_smoothlengthValueChanged(app, event)
            val = floor(app.slider_smoothlength.Value);
            app.slider_smoothlength.Value = val;
            app = loadPS(app);
            app = plot_boxes(app);
            app = plot_PeaksValleySignal(app);
        end

        % Value changed function: slider_prop
        function slider_propValueChanged(app, event)
            value = app.slider_prop.Value;
            app = loadPS(app);
            app = plot_boxes(app);
            app = plot_PeaksValleySignal(app);
        end

        % Button pushed function: nextbut
        function nextbutButtonPushed(app, event)
            PSCa =app.PSCa;
            save([app.savedir,app.FileName(1:end-4),'_PSCa.mat'],'PSCa','-v7.3','-nocompression')
            exportapp(app.UIFigure, [app.savedir,app.FileName(1:end-4), '_peaks_detection.png']);
            app.num =mod(app.num, length(app.list))+1;
            if app.num==length(app.list)
               set(app.alldone,'Visible','on')
               set(app.finish,'Visible','on')
            end

            loadfiles(app);
            load_images(app);
            loadPS(app);
            plot_boxes(app);
            plot_PeaksValleySignal(app);
        end

        % Button pushed function: prevbut
        function prevbutButtonPushed(app, event)
            app.num =app.num-1;
            loadfiles(app);
            load_images(app);
            loadPS(app);
            plot_boxes(app);
            plot_PeaksValleySignal(app);
        end

        % Button pushed function: pushbutton_treatall
        function pushbutton_treatallButtonPushed(app, event)
            for f=1:length(app.list)
                app.num=f;
                loadfiles(app);
                load_images(app);
                loadPS(app);
                plot_boxes(app);
                plot_PeaksValleySignal(app);
                PSCa = app.PSCa;
                save([app.savedir,app.FileName(1:end-4),'_PSCa.mat'],'PSCa','-v7.3','-nocompression')
                exportapp(app.UIFigure, [app.savedir,app.FileName(1:end-4), '_peaks_detection.png']);
                if app.SavetracesCheckBox.Value
                    view = PeriodicSignalsCaView(PSCa,'SaveFolder',app.savedir,'SaveFileName',app.FileName(1:end-4));
                    view.IntensRadial(1);
                    view.IntensAngular(1)
                    view.PeaksValleys('sauv', 1);
                    view.PlotIntPkFourier(1)
                end
            end
            delete(app.UIFigure)
        end

        % Button pushed function: finish
        function finishButtonPushed(app, event)
            PSCa =app.PSCa;
            save([app.savedir,app.FileName(1:end-4),'_PSCa.mat'],'PSCa','-v7.3','-nocompression')
            exportapp(app.UIFigure, [app.savedir,app.FileName(1:end-4), '_peaks_detection.png']);
            for f=1:length(app.list)
                app.num=f;
                app.FileName = [app.list{app.num}(1:end-4), '_PSCa.mat'];
                load([app.savedir app.FileName])
                if app.SavetracesCheckBox.Value
                    view = PeriodicSignalsCaView(PSCa ,'SaveFolder',app.savedir,'SaveFileName',app.FileName(1:end-9));
                    view.IntensRadial(1);
                    view.IntensAngular(1)
                    view.PeaksValleys('sauv', 1);
                    view.PlotIntPkFourier(1)
                end
            end
            delete(app.UIFigure)
        end

        % Callback function: not associated with a component
        function DetrendCheckBoxValueChanged(app, event)
            value = app.DetrendCheckBox.Value;
            app = loadPS(app);
            app = plot_boxes(app);
            app = plot_PeaksValleySignal(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_signal = uiaxes(app.UIFigure);
            app.axes_signal.XTick = [];
            app.axes_signal.XTickLabel = '';
            app.axes_signal.YTick = [];
            app.axes_signal.YTickLabel = {};
            app.axes_signal.ZTick = [];
            app.axes_vecfield = uiaxes(app.UIFigure);
            app.axes_vecfield.XTick = [];
            app.axes_vecfield.YTick = [];
            app.pushbutton_treatall = uibutton(app.UIFigure, 'push');
            app.pushbutton_treatall.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_treatallButtonPushed, true);
            app.pushbutton_treatall.Text = 'treat all';
            app.pushbutton_next = uibutton(app.UIFigure, 'push');
            app.pushbutton_next.Text = 'Next random';
            app.nextbut = uibutton(app.UIFigure, 'push');
            app.nextbut.ButtonPushedFcn = createCallbackFcn(app, @nextbutButtonPushed, true);
            app.nextbut.Text = 'Next';
            app.prevbut = uibutton(app.UIFigure, 'push');
            app.prevbut.ButtonPushedFcn = createCallbackFcn(app, @prevbutButtonPushed, true);
            app.prevbut.Text = 'Previous';
            app.alldone = uilabel(app.UIFigure);
            app.alldone.Text = 'All done';
            app.text6 = uilabel(app.UIFigure);
            app.text6.Text = 'If all images are similar';
            app.text7 = uilabel(app.UIFigure);
            app.text7.Text = 'If images all need a different threshold';
            app.finish = uibutton(app.UIFigure, 'push');
            app.finish.ButtonPushedFcn = createCallbackFcn(app, @finishButtonPushed, true);
            app.finish.Text = 'Done';
            app.ref_type = uidropdown(app.UIFigure);
            app.ref_type.Items = {'Residence', 'Pixel Difference'};
            app.ref_type.ItemsData = [0 1];
            app.ref_type.ValueChangedFcn = createCallbackFcn(app, @ref_typeValueChanged, true);
            app.ref_type.Visible = 'off';
            app.ref_type.Value = 0;
            app.slider_prop = uislider(app.UIFigure);
            app.slider_prop.Limits = [0 1];
            app.slider_prop.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slider_prop.ValueChangedFcn = createCallbackFcn(app, @slider_propValueChanged, true);
            app.slider_smoothlength = uislider(app.UIFigure);
            app.slider_smoothlength.Limits = [0 1];
            app.slider_smoothlength.MajorTicks = [];
            app.slider_smoothlength.ValueChangedFcn = createCallbackFcn(app, @slider_smoothlengthValueChanged, true);
            app.directory = uilabel(app.UIFigure);
            app.direc = uilabel(app.UIFigure);
            app.direc.Text = 'Static Text';
            app.SavetracesCheckBox = uicheckbox(app.UIFigure);
            app.SavetracesCheckBox.Text = 'Save traces ?';
            app.SavetracesCheckBox.Value = true;
            app.Point_error = uilabel(app.UIFigure);
            app.Point_error.Visible = 'off';
            app.Point_error.Text = 'Not enough points to track';
            app.SmoothlengthLabel = uilabel(app.UIFigure);
            app.SmoothlengthLabel.Text = 'Smoothlength';
            app.PropLabel = uilabel(app.UIFigure);
            app.PropLabel.Text = 'Prop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIcontrollerCa_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end