classdef GUIcontroller_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PropLabel            matlab.ui.control.Label
        SmoothlengthLabel    matlab.ui.control.Label
        Point_error          matlab.ui.control.Label
        SavetracesCheckBox   matlab.ui.control.CheckBox
        direc                matlab.ui.control.Label
        directory            matlab.ui.control.Label
        slider_smoothlength  matlab.ui.control.Slider
        slider_prop          matlab.ui.control.Slider
        ref_type             matlab.ui.control.DropDown
        finish               matlab.ui.control.Button
        text7                matlab.ui.control.Label
        text6                matlab.ui.control.Label
        alldone              matlab.ui.control.Label
        prevbut              matlab.ui.control.Button
        nextbut              matlab.ui.control.Button
        pushbutton_next      matlab.ui.control.Button
        pushbutton_treatall  matlab.ui.control.Button
        axes_vecfield        matlab.ui.control.UIAxes
        axes_signal          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        curdir % Description
        savedir
        list
        num
        PixelSize
        SamplingFrequency
        ref_type_val
        FileName
        results_pathname
        Signal
        Time
        Var
        PS
        im1
        smoothlength
        prop
    end
    
    methods (Access = public)

        function app = loadresults(app)
            pos = strfind(app.curdir, filesep);
            app.directory.Text = num2str(app.curdir(pos(end-1)+1:end-1));
            app.direc.Text = [num2str(app.num) '/' num2str(length(app.list))];
            app.FileName = [app.list{app.num}(1:end-4), '_results.mat'];
            app.results_pathname=[app.savedir, app.FileName(1:end-12),'.xlsx'];
            [app.Signal, app.Time, app.Var]=getsignal(app.savedir,app.FileName);
        end
        
        function app = loadPS(app)
            app.PS = PeriodicSignalsModel(app.Signal,'PixelSize',app.PixelSize,'SamplingFrequency',app.SamplingFrequency,'results_pathname',app.results_pathname,'col_line',1);

            if app.ref_type_val == 0
                app.PS.determineCenter();
            else
                app.PS.determineCenter(app.Var);
            end

            app.PS.findFourierPeriod(app.PS.Amplitude, app.PS.SamplingFrequency);   
            app.PS.interpolateSignal(app.Time, app.PS.Amplitude, 1);   
            app.PS.findPhaseShift(app.PS.InterpTime, app.PS.InterpSignal, mode(app.PS.FourierPeriod));
            app.PS.averageSignal;
            app.PS.ExtractSignal('Smoothlength',app.slider_smoothlength.Value,'prop',app.slider_prop.Value);
        end

        function app = load_images(app)
            filename = [app.curdir app.list{app.num}];
            im = tiffreadVolume(filename);
            app.slider_smoothlength.Limits = [0 min([100,size(im,3)])];
            app.slider_smoothlength.MajorTicks = int8(0:10:app.slider_smoothlength.Limits(end));
            app.slider_smoothlength.MinorTicks = int8(0:1:app.slider_smoothlength.Limits(end));
            

            im1 = double(im(:,:,1));
            im1=im1(2:end,1:end);
            %im1(:,:) = im1(:,:) - mean2(im1(:,:));
            app.im1 = im1;
        end

        function app = plot_vectorfield(app)
            imshow(app.im1,[],'parent',app.axes_vecfield);
            hold(app.axes_vecfield, 'on')
            [pks, Locs] = findpeaks(app.PS.Signal.FinalSignalScaled, 'SortStr', 'descend');
            quiver(app.PS.Center(app.PS.signalarea,2),app.PS.Center(app.PS.signalarea,1),app.PS.xPosition(app.PS.signalarea,Locs(1)),app.PS.yPosition(app.PS.signalarea,Locs(1)), 'm','parent',app.axes_vecfield)
            quiver(app.PS.Center(~app.PS.signalarea,2),app.PS.Center(~app.PS.signalarea,1),app.PS.xPosition(~app.PS.signalarea,Locs(1)),app.PS.yPosition(~app.PS.signalarea,Locs(1)), 'c','parent',app.axes_vecfield)
            hold(app.axes_vecfield, 'off')
        end

        function app = plot_PeaksValleySignal(app)
            pos=app.PS.Signal.posper(:,:,1);
            M=app.PS.Signal.Mper(:,:,1);
                
            if sum(app.PS.signalarea)>0
                plot(app.PS.Signal.FinalTimeScaled,app.PS.Signal.FinalSignalScaled,'parent',app.axes_signal)
                hold(app.axes_signal, 'on')
                plot(app.PS.Signal.xmc,app.PS.Signal.mmvg,'+c','parent',app.axes_signal)
                plot(app.PS.Signal.posM,app.PS.Signal.M,'+k','parent',app.axes_signal)
                plot(app.PS.Signal.xmr,app.PS.Signal.mmvd,'+g','parent',app.axes_signal) 
                plot(pos(:),M(:),'+m','linewidth',2,'parent',app.axes_signal)
                xlabel('Time (s)','parent',app.axes_signal)
                ylabel('Displacement (\mum)','parent',app.axes_signal)
                app.axes_signal.XTick = 0:min(1,max(app.PS.Signal.FinalTimeScaled)):max(app.PS.Signal.FinalTimeScaled);
                app.axes_signal.YTick = 0:min(0.5,max(app.PS.Signal.FinalSignalScaled)):max(app.PS.Signal.FinalSignalScaled);
                app.axes_signal.XTickLabel = 0:min(1,max(app.PS.Signal.FinalTimeScaled)):max(app.PS.Signal.FinalTimeScaled);
                app.axes_signal.YTickLabel = 0:min(0.5,max(app.PS.Signal.FinalSignalScaled)):max(app.PS.Signal.FinalSignalScaled);

                hold(app.axes_signal, 'off')
            else
                set(app.Point_error,'Visible','on')
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %%Mettre ce que doit faire le code au démarrage
            
            %set(app.figure1,'toolbar','figure');
            
            set(app.alldone,'Visible','off')
            set(app.finish,'Visible','off')
            p = inputParser;
            addRequired(p, 'Directory', @isstr);
            addParameter(p, 'SaveDir', pwd, @isstr);
            addParameter(p, 'PixelSize', 1);
            addParameter(p, 'SamplingFrequency', 1);
            
            parse(p, varargin{:});
            
            app.curdir = p.Results.Directory;
            app.savedir = p.Results.SaveDir;
            app.PixelSize = p.Results.PixelSize;
            app.SamplingFrequency = p.Results.SamplingFrequency;
            app.num = 1;

            app.list = listTiffs(app.curdir);
            
            app = loadresults(app); 
            load_images(app);

            app.slider_smoothlength.Value = 20;
            app.slider_prop.Value = 0.1;
            loadPS(app);  
            plot_vectorfield(app);
            plot_PeaksValleySignal(app);
           
            
        end

        % Value changed function: ref_type
        function ref_typeValueChanged(app, event)
            app.ref_type_val = get(app.ref_type,'Value');
            app = loadPS(app);
            app = plot_vectorfield(app);
            app = plot_PeaksValleySignal(app);
        end

        % Value changed function: slider_smoothlength
        function slider_smoothlengthValueChanged(app, event)
            val = floor(app.slider_smoothlength.Value);
            app.slider_smoothlength.Value = val;
            app = loadPS(app);
            app = plot_vectorfield(app);
            app = plot_PeaksValleySignal(app);
        end

        % Value changed function: slider_prop
        function slider_propValueChanged(app, event)
            value = app.slider_prop.Value;
            app = loadPS(app);
            app = plot_vectorfield(app);
            app = plot_PeaksValleySignal(app);
        end

        % Button pushed function: nextbut
        function nextbutButtonPushed(app, event)
            PS = app.PS;
            save([app.savedir,app.FileName(1:end-12),'_PS.mat'],'PS','-v7.3','-nocompression')
            exportapp(app.UIFigure, [app.savedir app.FileName(1:end-12) '_peaks_detection.png']);
  

            app.num =mod(app.num, length(app.list))+1;
            if app.num==length(app.list)
               set(app.alldone,'Visible','on')
               set(app.finish,'Visible','on')
            end
            

            loadresults(app);
            load_images(app);
            loadPS(app);
            plot_vectorfield(app);
            plot_PeaksValleySignal(app);
        end

        % Button pushed function: prevbut
        function prevbutButtonPushed(app, event)
            app.num =app.num-1;
            loadresults(app);
            load_images(app);
            loadPS(app);
            plot_vectorfield(app);
            plot_PeaksValleySignal(app);
        end

        % Button pushed function: pushbutton_treatall
        function pushbutton_treatallButtonPushed(app, event)
            for f=1:length(app.list)
                app.num=f;
                loadresults(app);
                if~exist([app.savedir app.FileName(1:end-12),'_PS.mat'],'file')
                    load_images(app);
                    loadPS(app);
                    plot_vectorfield(app);
                    app = plot_PeaksValleySignal(app);
                    PS = app.PS;
                    save([app.savedir,app.FileName(1:end-12),'_PS.mat'],'PS','-v7.3','-nocompression')
                    exportapp(app.UIFigure, [app.savedir app.FileName(1:end-12) '_peaks_detection.png']);
                    if (app.SavetracesCheckBox.Value) && (sum(PS.signalarea)>0)
                        view = PeriodicSignalsView(PS,'SaveFolder',app.savedir,'SaveFileName',app.FileName(1:end-12));
                        view.PhaseShift(1);
                        view.DisplacementsatPeaks(1);
                        view.PeaksValleysSignal('sauv', 1);
                        view.FourierAmpl(1)
                    end
                end
            end
            delete(app.UIFigure)
        end

        % Button pushed function: finish
        function finishButtonPushed(app, event)
            for f=1:length(app.list)
                app.num=f;
                app.FileName = [app.list{app.num}(1:end-4), '_PS.mat'];
                load([app.savedir app.FileName])
                if (app.SavetracesCheckBox.Value) && (sum(PS.signalarea)>0)
                    view = PeriodicSignalsView(PS,'SaveFolder',app.savedir,'SaveFileName',app.FileName(1:end-12));
                    view.PhaseShift(1);
                    view.DisplacementsatPeaks(1);
                    view.PeaksValleysSignal('sauv', 1);
                    view.FourierAmpl(1)
                end
            end
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_signal = uiaxes(app.UIFigure);
            app.axes_signal.XTick = [];
            app.axes_signal.XTickLabel = '';
            app.axes_signal.YTick = [];
            app.axes_signal.YTickLabel = {};
            app.axes_signal.ZTick = [];
            app.axes_vecfield = uiaxes(app.UIFigure);
            app.axes_vecfield.XTick = [];
            app.axes_vecfield.YTick = [];
            app.pushbutton_treatall = uibutton(app.UIFigure, 'push');
            app.pushbutton_treatall.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_treatallButtonPushed, true);
            app.pushbutton_treatall.Text = 'treat all';
            app.pushbutton_next = uibutton(app.UIFigure, 'push');
            app.pushbutton_next.Text = 'Next random';
            app.nextbut = uibutton(app.UIFigure, 'push');
            app.nextbut.ButtonPushedFcn = createCallbackFcn(app, @nextbutButtonPushed, true);
            app.nextbut.Text = 'Next';
            app.prevbut = uibutton(app.UIFigure, 'push');
            app.prevbut.ButtonPushedFcn = createCallbackFcn(app, @prevbutButtonPushed, true);
            app.prevbut.Text = 'Previous';
            app.alldone = uilabel(app.UIFigure);
            app.alldone.Text = 'All done';
            app.text6 = uilabel(app.UIFigure);
            app.text6.Text = 'If all images are similar';
            app.text7 = uilabel(app.UIFigure);
            app.text7.Text = 'If images all need a different threshold';
            app.finish = uibutton(app.UIFigure, 'push');
            app.finish.ButtonPushedFcn = createCallbackFcn(app, @finishButtonPushed, true);
            app.finish.Text = 'Done';
            app.ref_type = uidropdown(app.UIFigure);
            app.ref_type.Items = {'Residence', 'Pixel Difference'};
            app.ref_type.ItemsData = [0 1];
            app.ref_type.ValueChangedFcn = createCallbackFcn(app, @ref_typeValueChanged, true);
            app.ref_type.Value = 1;
            app.slider_prop = uislider(app.UIFigure);
            app.slider_prop.Limits = [0 1];
            app.slider_prop.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.slider_prop.ValueChangedFcn = createCallbackFcn(app, @slider_propValueChanged, true);
            app.slider_smoothlength = uislider(app.UIFigure);
            app.slider_smoothlength.Limits = [0 1];
            app.slider_smoothlength.MajorTicks = [];
            app.slider_smoothlength.ValueChangedFcn = createCallbackFcn(app, @slider_smoothlengthValueChanged, true);
            app.directory = uilabel(app.UIFigure);
            app.direc = uilabel(app.UIFigure);
            app.direc.Text = 'Static Text';
            app.SavetracesCheckBox = uicheckbox(app.UIFigure);
            app.SavetracesCheckBox.Text = 'Save traces ?';
            app.SavetracesCheckBox.Value = true;
            app.Point_error = uilabel(app.UIFigure);
            app.Point_error.Visible = 'off';
            app.Point_error.Text = 'Not enough points to track';
            app.SmoothlengthLabel = uilabel(app.UIFigure);
            app.SmoothlengthLabel.Text = 'Smoothlength';
            app.PropLabel = uilabel(app.UIFigure);
            app.PropLabel.Text = 'Prop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIcontroller_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end