classdef GUIklt_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        mdisp                  matlab.ui.control.EditField
        text40                 matlab.ui.control.Label
        pushbutton_stop        matlab.ui.control.Button
        pushbutton_runklt      matlab.ui.control.Button
        text_comptime          matlab.ui.control.Label
        text37                 matlab.ui.control.Label
        text_curdir            matlab.ui.control.Label
        pushbutton_testklt     matlab.ui.control.Button
        text_npoints           matlab.ui.control.Label
        text33                 matlab.ui.control.Label
        pushbutton_testpoints  matlab.ui.control.Button
        uipanel2               matlab.ui.container.Panel
        edit_method            matlab.ui.control.EditField
        text39                 matlab.ui.control.Label
        edit_threshold         matlab.ui.control.EditField
        edit_maxiteration      matlab.ui.control.EditField
        edit_removedrift       matlab.ui.control.EditField
        edit_dt                matlab.ui.control.EditField
        edit_winsize           matlab.ui.control.EditField
        edit_pyramidlevel      matlab.ui.control.EditField
        text13                 matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        text11                 matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        text9                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        edit_mask              matlab.ui.control.EditField
        edit_mindist           matlab.ui.control.EditField
        text4                  matlab.ui.control.Label
        text2                  matlab.ui.control.Label
        axes_waitbar           matlab.ui.control.UIAxes
        axes_vectors           matlab.ui.control.UIAxes
        axes_points            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        curdir % Description
        savedir
        list
        num
        PixelSize
        SamplingFrequency
        param
        savename
        stop_now
        tfin
        i
        pyr1
        pyr2
        t1
        t2
        im1
        im2
        im_delta
        pts
        sp
        err
        conver
        med
        results
    end

    methods (Access = private)
        
        function app = findcontraction(app, kernel_size)
            I2 = 1 + app.param.dt : 1 : app.tfin;
            I1 = 1 : 1 : app.tfin - app.param.dt;
            app.t1 = I1(app.i);
            app.t2 = I2(app.i);
            
                app = loadimages(app);
                im_delta = app.im2-app.im1;
                %im_delta     = stdfilt(im_delta, true(kernel_size*2+1));
                app.im_delta = im_delta;
        end
        
        function app = findpoints(app)
            
            % This function finds local intensity maxima aver the image,
            % and keeps those which fall within a binary mask, if given
            
            features = 10000000;
            [ptst(:,1), ptst(:,2)] = localMaximum_h(app.im1, app.param.minDist, app.param.excludeEqualPoints, features);
            if app.param.mask == 1
                load([app.savedir app.savename '_mask.mat']);
                ind = sub2ind(size(mask), ptst(:,1), ptst(:,2));
                ind2 = mask(ind)>0;
                pts(:,:) = ptst(ind2,:);
                % pts(:,:) = ptst;
            else
                pts(:,:)=ptst(:,:);
            end

            app.text_npoints.Text = num2str(length(pts));
            app.pts=pts;
            
            %%%%%%%%%

            imshow(app.im1,[],'parent',app.axes_points);
            hold(app.axes_points,'on');
            plot(app.pts(:,2), app.pts(:,1),'.r','parent',app.axes_points);
            hold(app.axes_points,'off');
        end
        
        function app = klt(app)
            WindowSize = repmat(app.param.winSize,app.param.pyramidLevel,1);
            MaxIteration = repmat(app.param.maxIteration,1,app.param.pyramidLevel);
            Threshold = repmat(app.param.threshold,1,app.param.pyramidLevel);
            
            if app.param.gpu==0
            [app.sp, conver, app.err] = pyrLK_2D(app.pyr1,app.pyr2,app.pts,WindowSize,MaxIteration,Threshold);
            else
                [app.sp, conver, app.err] = pyrLK_2Dgpu(app.pyr1,app.pyr2,app.pts,WindowSize,MaxIteration,Threshold);
            end
            app.conver = prod(conver,2)>0;
            %sum(app.conver)
            app.med=nanmedian(sqrt(app.sp(app.conver,1).^2+app.sp(app.conver,2).^2));
            
            %%%%%%%%%%
            imshow(app.im1,[],'parent',app.axes_vectors);
            hold(app.axes_vectors,'on');
            %quiver(app.pts(:, 2), app.pts(:,1), app.sp(:,2), app.sp(:,1), 5, 'Color', [1 0 0], 'LineWidth', 1, 'parent', app.axes_vectors);
            %quiver(app.pts(app.conver, 2), app.pts(app.conver,1), app.sp(app.conver,2), app.sp(app.conver,1), 5, 'Color', [0.1 0.8 0.1], 'parent', app.axes_vectors);
            text(10, 24, [num2str(app.t1) ' - ' num2str(app.t2)],'Color','w','FontSize',14); drawnow;
            hold(app.axes_vectors,'off');
            %%%%%%%%%%
            
            if app.param.removedrift == 1
                app.sp(:,2) = app.sp(:,2) - mean(app.sp(:,2));
                app.sp(:,1) = app.sp(:,1) - mean(app.sp(:,1));
                %%%%%%%%%%
                hold(app.axes_vectors,'on');
                quiver(app.pts(app.conver,2),app.pts(app.conver,1),app.sp(app.conver,2),app.sp(app.conver,1),5,'m','parent',app.axes_vectors);
                hold(app.axes_vectors,'off');
                %%%%%%%%%%
            end
        end
        
        function app = runklt(app)
            
            set(app.pushbutton_runklt, 'enable', 'off');
            set(app.pushbutton_stop, 'enable', 'on');
            update_waitbar(app,0);
            if app.param.stack
                infof=imfinfo ([app.curdir app.list{app.num}]);
                app.tfin = length(infof);
            else
            app.tfin = length([app.list]);
            
            end
            if app.param.method == 0
                I2 = 1 + app.param.dt : app.param.dt : app.tfin;
                I1 = ones(1, length(I2));
            elseif app.param.method == 1
                I2 = 1 + app.param.dt : 1 : app.tfin;
                I1 = 1 : 1 : app.tfin - app.param.dt;
            end
               tic
            for i = 1 : length (I1)
                if(~app.stop_now)
                disp([num2str(i) '|' num2str(length(I1))])
                app.i = i;
                app.t1 = I1(i);
                app.t2 = I2(i);
            
                app = loadimages(app);
                if app.param.method == 1 || i == 1
                    app = findpoints(app);
                end
                app = makepyramid(app);
                app = klt(app);
                app = app.findcontraction(10);
                app = outputdata(app);
            
                update_waitbar(app,(app.i/length(I1)));
                end
            end
            toc
            
            results = app.results;
            save([app.savedir app.savename  '_results.mat'],'results');
            exportapp(app.figure1, [app.savedir app.savename '_results.png']);
        end
        
        function app = loadimages(app)
            if app.param.stack
                filename = [app.curdir app.list{app.num}];
                im1 = double(imread(filename,app.t1));
                im1=im1(2:end,:);
            else
            filename = [app.curdir app.list{app.t1}];
            im1 = double(imread(filename));
            im1=im1(2:end,:);
            end
            im1(:,:) = im1(:,:) - mean2(im1(:,:));
            app.im1 = im1;
            if app.param.stack
                filename = [app.curdir app.list{app.num}];
                im2 = double(imread(filename,app.t2));
                im2=im2(2:end,:);
            else
            filename = [app.curdir app.list{app.t2}];
            im2 = double(imread(filename));
            im2=im2(2:end,:);
            end
            im2(:,:) = im2(:,:) - mean2(im2(:,:));
            app.im2 = im2;
        end
        
        function app = makepyramid(app)
            WindowSize = repmat(app.param.winSize,app.param.pyramidLevel,1);
            app.pyr1 = makePyramid_2D(app.im1,app.param.pyramidLevel,app.param.blurRadius,WindowSize);
            app.pyr2 = makePyramid_2D(app.im2,app.param.pyramidLevel,app.param.blurRadius,WindowSize);
        end
        
        function app = outputdata(app)
            app.results(app.i).pts1 = app.pts;
            app.results(app.i).pts2 = app.pts + app.sp;
            app.results(app.i).sp = app.sp;
            app.results(app.i).err = app.err;
            app.results(app.i).conver = app.conver;
            app.results(app.i).var = app.im_delta;
        end
        
        function update_waitbar(app, value)
            %h=app.axes_waitbar;set(h,'Visible','On');
            %axes(h);cla;h=patch([0,value,value,0],[0,0,1,1],'b');
            %axis([0,1,0,1]);axis off;drawnow;
            
            set(app.axes_waitbar,'Visible','On');
            patch([0,value,value,0],[0,0,1,1],'g','Parent',app.axes_waitbar);
            set(app.axes_waitbar,'xLim',[0 1],'yLim',[0 1]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            prm.minDist=20;
            prm.mask=0;
            prm.pyramidLevel=3;
            prm.winSize=8;
            prm.dt=1;
            prm.method=1;
            prm.removedrift=0;
            prm.maxIteration=20;
            prm.threshold=0.1;
            prm.auto=0;
            
            p = inputParser;
            addRequired(p, 'Directory', @isstr);
            addParameter(p, 'SaveDir', pwd, @isstr);
            addParameter(p, 'Parameters', prm, @isstruct);
            addParameter(p, 'num', 1);
            parse(p, varargin{:});
            
            app.curdir = p.Results.Directory;
            app.savedir = p.Results.SaveDir;
            app.param = p.Results.Parameters;
            app.list = listTiffs(app.curdir);
            app.num=p.Results.num;
            if app.param.stack
                app.savename =  app.list{app.num}(1:end-4);
                app.text_curdir.Text = num2str(app.savename);
            else

            
            fl = regexp(app.curdir(1:end-1),filesep);
            app.savename = app.curdir(fl(end)+1:end-1);
            app.text_curdir.Text = num2str(app.curdir);
            
            end
            app.stop_now=0;
            set(app.pushbutton_stop, 'enable', 'off');
            

            app.edit_mindist.Value = num2str(app.param.minDist);
            app.edit_mask.Value = num2str(app.param.mask);
            app.edit_pyramidlevel.Value = num2str(app.param.pyramidLevel);
            app.edit_winsize.Value = num2str(app.param.winSize);
            app.edit_dt.Value = num2str(app.param.dt);
            app.edit_method.Value = num2str(app.param.method);
            app.edit_removedrift.Value = num2str(app.param.removedrift);
            app.edit_maxiteration.Value = num2str(app.param.maxIteration);
            app.edit_threshold.Value = num2str(app.param.threshold);
            
            % other
            app.param.excludeEqualPoints = 1;
            app.param.blurRadius = -1;
            if app.param.auto == 1
                app = runklt (app);
                %    drawnow;
                delete(app.figure1);
            else
             
             uiwait(app.figure1);
            end
        end

        % Value changed function: edit_dt
        function edit_dt_Callback(app, event)
            value = app.edit_dt.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.dt = input;
            end
        end

        % Value changed function: edit_mask
        function edit_mask_Callback(app, event)
            value = app.edit_mask.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.mask = input;
            end
        end

        % Value changed function: edit_maxiteration
        function edit_maxiteration_Callback(app, event)
            value = app.edit_maxiteration.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')

                return
            else
                app.param.maxIteration = input;
            end
            
        end

        % Value changed function: edit_method
        function edit_method_Callback(app, event)
            value = app.edit_method.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.method = input;
            end
            
        end

        % Value changed function: edit_mindist
        function edit_mindist_Callback(app, event)
            value = app.edit_mindist.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.minDist = input;
            end
        end

        % Value changed function: edit_pyramidlevel
        function edit_pyramidlevel_Callback(app, event)
            value = app.edit_pyramidlevel.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.pyramidLevel = input;
            end
        end

        % Value changed function: edit_removedrift
        function edit_removedrift_Callback(app, event)
            value = app.edit_removedrift.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.removedrift = input;
            end
        end

        % Value changed function: edit_threshold
        function edit_threshold_Callback(app, event)
            value = app.edit_threshold.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.threshold = input;
            end
        end

        % Value changed function: edit_winsize
        function edit_winsize_Callback(app, event)
            value = app.edit_winsize.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.winSize = input;
            end
        end

        % Button pushed function: pushbutton_stop
        function pushbutton_stop_Callback(app, event)
            
            app.stop_now = 1;
            delete(app.figure1)
            
        end

        % Button pushed function: pushbutton_testklt
        function pushbutton_testklt_Callback(app, event)
            
            tic
            oldpointer = get(app.figure1, 'pointer');
            set(app.figure1, 'pointer', 'watch'); drawnow;
            
            app.t1 = 1;
            app.t2 = 1 + app.param.dt;
            app = loadimages(app);
            app = findpoints(app);
            app = makepyramid(app);
            app = klt(app);
            
            set(app.figure1, 'pointer', oldpointer); drawnow;
            app.text_comptime.Text = num2str(toc);
            app.mdisp.Text =  num2str(app.med);
        end

        % Button pushed function: pushbutton_testpoints
        function pushbutton_testpoints_Callback(app, event)
            %% pushbuttons


            app.t1 = 1;
            app.t2 = 2;
            app = loadimages(app);
            app = findpoints(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIklt';
            app.figure1.Tag = 'figure1';
            app.axes_points = uiaxes(app.figure1);
            app.axes_points.XTick = [];
            app.axes_points.YTick = [];
            app.axes_points.Tag = 'axes_points';
            app.axes_vectors = uiaxes(app.figure1);
            app.axes_vectors.XTick = [];
            app.axes_vectors.YTick = [];
            app.axes_vectors.Tag = 'axes_vectors';
            app.axes_waitbar = uiaxes(app.figure1);
            app.axes_waitbar.XTick = [];
            app.axes_waitbar.YTick = [];
            app.axes_waitbar.Tag = 'axes_waitbar';
            app.axes_waitbar.Visible = 'off';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Find Points';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Min Distance';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Mask';
            app.edit_mindist = uieditfield(app.uipanel1, 'text');
            app.edit_mindist.ValueChangedFcn = createCallbackFcn(app, @edit_mindist_Callback, true);
            app.edit_mindist.Tag = 'edit_mindist';
            app.edit_mindist.Value = 'Edit Text';
            app.edit_mask = uieditfield(app.uipanel1, 'text');
            app.edit_mask.ValueChangedFcn = createCallbackFcn(app, @edit_mask_Callback, true);
            app.edit_mask.Tag = 'edit_mask';
            app.edit_mask.Value = 'Edit Text';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'KLT';
            app.uipanel2.Tag = 'uipanel2';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Pyramid Level';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Window Size';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Delta t';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Threshold';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Iterations';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Remove Drift';
            app.edit_pyramidlevel = uieditfield(app.uipanel2, 'text');
            app.edit_pyramidlevel.ValueChangedFcn = createCallbackFcn(app, @edit_pyramidlevel_Callback, true);
            app.edit_pyramidlevel.Tag = 'edit_pyramidlevel';
            app.edit_pyramidlevel.Value = 'Edit Text';
            app.edit_winsize = uieditfield(app.uipanel2, 'text');
            app.edit_winsize.ValueChangedFcn = createCallbackFcn(app, @edit_winsize_Callback, true);
            app.edit_winsize.Tag = 'edit_winsize';
            app.edit_winsize.Value = 'Edit Text';
            app.edit_dt = uieditfield(app.uipanel2, 'text');
            app.edit_dt.ValueChangedFcn = createCallbackFcn(app, @edit_dt_Callback, true);
            app.edit_dt.Tag = 'edit_dt';
            app.edit_dt.Value = 'Edit Text';
            app.edit_removedrift = uieditfield(app.uipanel2, 'text');
            app.edit_removedrift.ValueChangedFcn = createCallbackFcn(app, @edit_removedrift_Callback, true);
            app.edit_removedrift.Tag = 'edit_removedrift';
            app.edit_removedrift.Value = 'Edit Text';
            app.edit_maxiteration = uieditfield(app.uipanel2, 'text');
            app.edit_maxiteration.ValueChangedFcn = createCallbackFcn(app, @edit_maxiteration_Callback, true);
            app.edit_maxiteration.Tag = 'edit_maxiteration';
            app.edit_maxiteration.Value = 'Edit Text';
            app.edit_threshold = uieditfield(app.uipanel2, 'text');
            app.edit_threshold.ValueChangedFcn = createCallbackFcn(app, @edit_threshold_Callback, true);
            app.edit_threshold.Tag = 'edit_threshold';
            app.edit_threshold.Value = 'Edit Text';
            app.text39 = uilabel(app.uipanel2);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Method';
            app.edit_method = uieditfield(app.uipanel2, 'text');
            app.edit_method.ValueChangedFcn = createCallbackFcn(app, @edit_method_Callback, true);
            app.edit_method.Tag = 'edit_method';
            app.edit_method.Value = 'Edit Text';
            app.pushbutton_testpoints = uibutton(app.figure1, 'push');
            app.pushbutton_testpoints.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_testpoints_Callback, true);
            app.pushbutton_testpoints.Tag = 'pushbutton_testpoints';
            app.pushbutton_testpoints.Text = 'find points';
            app.text33 = uilabel(app.figure1);
            app.text33.Tag = 'text33';
            app.text33.Text = 'number of points found:';
            app.text_npoints = uilabel(app.figure1);
            app.text_npoints.Tag = 'text_npoints';
            app.text_npoints.Text = '';
            app.pushbutton_testklt = uibutton(app.figure1, 'push');
            app.pushbutton_testklt.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_testklt_Callback, true);
            app.pushbutton_testklt.Tag = 'pushbutton_testklt';
            app.pushbutton_testklt.Text = 'test klt';
            app.text_curdir = uilabel(app.figure1);
            app.text_curdir.Tag = 'text_curdir';
            app.text_curdir.Text = 'Static Text';
            app.text37 = uilabel(app.figure1);
            app.text37.Tag = 'text37';
            app.text37.Text = 'computation time 1 step:';
            app.text_comptime = uilabel(app.figure1);
            app.text_comptime.Tag = 'text_comptime';
            app.text_comptime.Text = '';
            app.pushbutton_runklt = uibutton(app.figure1, 'push');
            app.pushbutton_runklt.Tag = 'pushbutton_runklt';
            app.pushbutton_runklt.Text = 'run klt';
            app.pushbutton_stop = uibutton(app.figure1, 'push');
            app.pushbutton_stop.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_stop_Callback, true);
            app.pushbutton_stop.Tag = 'pushbutton_stop';
            app.pushbutton_stop.Text = 'stop';
            app.text40 = uilabel(app.figure1);
            app.text40.Tag = 'text40';
            app.text40.Text = 'median displacement:';
            app.mdisp = uieditfield(app.figure1, 'text');
            app.mdisp.Tag = 'mdisp';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIklt_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end