classdef GUImask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ThresholdLabel    matlab.ui.control.Label
        text_fsize        matlab.ui.control.Label
        fsize             matlab.ui.control.EditField
        invert_mask       matlab.ui.control.CheckBox
        auto_or_not       matlab.ui.control.DropDown
        th_type           matlab.ui.control.DropDown
        finish            matlab.ui.control.Button
        text7             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        imnum             matlab.ui.control.Label
        alldone           matlab.ui.control.Label
        th                matlab.ui.control.EditField
        prevbut           matlab.ui.control.Button
        nextbut           matlab.ui.control.Button
        slider_threshold  matlab.ui.control.Slider
        text_dossier      matlab.ui.control.Label
        pushbutton_next   matlab.ui.control.Button
        treatall          matlab.ui.control.Button
        axes_final        matlab.ui.control.UIAxes
        axes_mask         matlab.ui.control.UIAxes
        axes_original     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        savedir
        param
        num
        dirs
        curdir
        list
        thlist
        savename
        filename
        im1
        th_type_val
        auto_val
        threshold
        mask
    end
    
    methods (Access = private)
        
        function app = make_mask(app)
            %%%%%%%%%% When executed, this function calculates the binary mask of the image with the parameters given by the user %%%%%%%%%%
            if ~isempty(app.list)
                if app.param.stack
                    app.filename = [app.curdir app.list{app.num}];
                else
                    list = listTiffs(app.curdir);
                    app.filename = [app.curdir list{1}];
                end
            end
            
            im1 = im2uint8(mat2gray(imread(app.filename)));
            im1=im1(2:end,1:end);
            
            MA=MakeMaskModel(im1,'Method',app.th_type_val,'Auto',app.auto_val,'Th',app.threshold,'Fsize',app.param.fsize);
            
            MA.MakeMask;
            % im1=im1(1:end-1,1:end);
            if app.invert_mask.Value
            app.mask= ~MA.Mask;
            else
                app.mask=MA.Mask;
            end
            
            if app.param.stack
            app.savename = app.list{app.num}(1:end-4);
            else
              fl = regexp(app.curdir(1:end-1),filesep);
              app.savename = app.curdir(fl(end)+1:end-1);
              app.curdir(fl(end)+1:end-1);
            end
        end
        
        function show_mask(app)
            im1 = im2uint8(mat2gray(imread(app.filename)));
            im1=im1(2:end,:);
            im2 = im1;
            
            im2(~app.mask) = 0;
            
            imshow(im1,[5/100*min(im1(:)) 90/100*max(im1(:))],'parent',app.axes_original)
            colormap(gray)
            % imcontrast(app.axes_original)
            imshow(app.mask,'parent',app.axes_mask)
            imshow(im2,[0 90/100*max(im2(:))],'parent',app.axes_final)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.slider_threshold.Limits = [0 255];
            
            
            set(app.alldone,'Visible','off')
            set(app.finish,'Visible','off')
            
            % Import the variables
            app.curdir = varargin{1};
            app.savedir = varargin{2};
            app.param=varargin{3};
            app.num=1;
            
            
            app.list = listTiffs(app.curdir);
            if app.param.stack
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.list))];
                app.thlist=NaN(1,length(app.list));
            else
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.dirs))];
                app.thlist=NaN(1,length(app.dirs));
            end
            
            if isfield(app.param,'fsize')
                app.fsize.Value = num2str(app.param.fsize);
            else
                app.param.fsize=3;
                app.fsize.Value = num2str(app.param.fsize);
            end
            
            
            if app.param.stack
               app.savename = app.list{app.num}(1:end-4);
               filesep = app.curdir(end);
               pos = strfind(app.curdir, filesep);
               if app.param.Ca == 0
                    app.text_dossier.Text = app.curdir(pos(end-1)+1:end-1);
                else
                   app.text_dossier.Text = app.curdir(pos(end-2)+1:pos(end-1)-1);
               end
            else
            fl = regexp(app.curdir(1:end-1),filesep);
            app.savename = app.curdir(fl(end)+1:end-1);
            
            app.text_dossier.Text = app.curdir;
            end
            
            
            if app.param.stack
                app.filename = [app.curdir app.list{app.num}];
            else
                list = listTiffs(app.curdir);
                app.filename = [app.curdir list{1}];
            end
            %% read image and make it 8 bit
            im1 = im2uint8(mat2gray(imread(app.filename,20)));
            app.im1=im1(2:end,1:end); %because of white line
            
            counts = imhist(app.im1, 256);
            app.threshold=otsuthresh(counts)*256;
            app.th.Value = num2str(app.threshold);
            % app.slider_threshold.Limits = 
            app.slider_threshold.Value = app.threshold;
            
            app.thlist(1)=app.threshold;
            app.auto_val=app.auto_or_not.Value;
            if app.auto_val==1
                if app.param.Ca == 0
                app.th_type.Value = 1;
                else
                    app.th_type.Value = 0;
                end
            end

            app.th_type_val=app.th_type.Value;
            
            app=make_mask(app);
            show_mask(app);
        end

        % Value changed function: auto_or_not
        function auto_or_notValueChanged(app, event)
            value = app.auto_or_not.Value;
            app.auto_val = value;
            if app.auto_val==2
                set(app.fsize,'Visible','off')
                set(app.text_fsize,'Visible','off')
                set(app.th_type,'Visible','off')
            else
                set(app.th_type,'Visible','on')
                if app.th_type_val==1
                    set(app.fsize,'Visible','off')
                    set(app.text_fsize,'Visible','off')
                else
                    set(app.fsize,'Visible','on')
                    set(app.text_fsize,'Visible','on')
                end
            
            end
            
            app = make_mask(app);
            show_mask(app);
        end

        % Value changed function: th_type
        function th_typeValueChanged(app, event)
            value = app.th_type.Value;
            app.th_type_val = value;
            if app.auto_val==2
                set(app.fsize,'Visible','off')
                set(app.text_fsize,'Visible','off')
                set(app.th_type,'Visible','off')
            else
                set(app.th_type,'Visible','on')
                if app.th_type_val==1
                    set(app.fsize,'Visible','off')
                    set(app.text_fsize,'Visible','off')
                else
                    set(app.fsize,'Visible','on')
                    set(app.text_fsize,'Visible','on')
                end
            
            end
            app = make_mask(app);
            show_mask(app);
        end

        % Value changed function: invert_mask
        function invert_maskValueChanged(app, event)
            app = make_mask(app);
            show_mask(app);
        end

        % Value changed function: slider_threshold
        function slider_thresholdValueChanged(app, event)
            value = app.slider_threshold.Value;
            app.threshold = value;
            app.th.Value = num2str(value);
            app = make_mask(app);
            show_mask(app);
        end

        % Value changed function: fsize
        function fsizeValueChanged(app, event)
            app.param.fsize = str2double(app.fsize.Value);
            counts = imhist(app.im1, 256);
            app.threshold=otsuthresh(counts)*256;
            app.th.Value = num2str(app.threshold);
            set(app.slider_threshold,'Value',app.threshold);
            app=make_mask(app);
            show_mask(app);
        end

        % Button pushed function: prevbut
        function prevbutButtonPushed(app, event)
            app.num=mod(app.num,length(app.list))-1;
             if app.param.stack
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.list))];
            else
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.dirs))];
             end
             app=make_mask(app);
             show_mask(app);
        end

        % Button pushed function: nextbut
        function nextbutButtonPushed(app, event)
                        
            mask=app.mask;
            save([app.savedir app.savename '_mask.mat'],'mask');
            exportapp(app.UIFigure, [app.savedir app.savename '_mask.png']);
            
            app.num=mod(app.num,length(app.list))+1;
            fl = regexp(app.curdir(1:end-1),filesep);
            if app.param.stack
                app.savename = app.list{app.num}(1:end-4);
            else
            app.savename = app.curdir(fl(end)+1:end-1);
            set(app.text_dossier, 'String', app.curdir);
            
            end


             if app.param.stack
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.list))];
            else
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.dirs))];
             end
            if app.num==length(app.thlist)
               set(app.alldone,'Visible','on')
               set(app.finish,'Visible','on')
            end
            
            
             app=make_mask(app);
             show_mask(app);
        end

        % Button pushed function: pushbutton_next
        function pushbutton_nextButtonPushed(app, event)
            if app.param.stack
                app.num=mod(app.num,length(app.list))+1;
            end
            f=randi(length(app.dirs),1);
            app.curdir=app.dirs{f};
            fl = regexp(app.curdir(1:end-1),filesep);
            if app.param.stack
                app.savename = app.list{app.num}(1:end-4);
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.list))];
            else
            app.savename = app.curdir(fl(end)+1:end-1);
            app.imnum.Text = [num2str(app.num),'/',num2str(length(app.dirs))];
            
            end
            app=make_mask(app);
            show_mask(app);
        end

        % Button pushed function: treatall
        function treatallButtonPushed(app, event)
            if app.param.stack
            
               for f=1:length(app.list)
                app.savename = app.list{f}(1:end-4);
                app.num=f;
                app.imnum.Text = [num2str(app.num),'/',num2str(length(app.list))];
                if~exist([app.savedir app.savename '_mask.mat'],'file')
                    app=make_mask(app);
                    show_mask(app);
                    mask=app.mask;
                    save([app.savedir app.savename '_mask.mat'],'mask');
                    exportapp(app.UIFigure, [app.savedir app.savename '_mask.png']);
                end
               end
            
            else
            for f=1:length(app.dirs)
                app.curdir=app.dirs{f};
                fl = regexp(app.curdir(1:end-1),filesep);
                app.savename = app.curdir(fl(end)+1:end-1);
                if~exist([app.savedir app.savename '_mask.mat'],'file')
                app=make_mask(app);
                show_mask(app);
                mask=app.mask;
                save([app.savedir app.savename '_mask.mat'],'mask');
                exportapp(app.UIFigure, [app.savedir app.savename '_mask.png']);
                end
            end
            
            end
            delete(app.UIFigure);
        end

        % Button pushed function: finish
        function finishButtonPushed(app, event)
            mask=app.mask;
            save([app.savedir app.savename '_mask.mat'],'mask');
            exportapp(app.UIFigure, [app.savedir app.savename '_mask.png']);
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_original = uiaxes(app.UIFigure);
            app.axes_mask = uiaxes(app.UIFigure);
            app.axes_final = uiaxes(app.UIFigure);
            app.treatall = uibutton(app.UIFigure, 'push');
            app.treatall.ButtonPushedFcn = createCallbackFcn(app, @treatallButtonPushed, true);
            app.treatall.Text = 'treat all';
            app.pushbutton_next = uibutton(app.UIFigure, 'push');
            app.pushbutton_next.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nextButtonPushed, true);
            app.pushbutton_next.Text = 'Next random';
            app.text_dossier = uilabel(app.UIFigure);
            app.text_dossier.Text = 'Static Text';
            app.slider_threshold = uislider(app.UIFigure);
            app.slider_threshold.Limits = [0 256];
            app.slider_threshold.MajorTicks = [0 50 100 150 200 250];
            app.slider_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_thresholdValueChanged, true);
            app.slider_threshold.MinorTicks = [0 0.0125 0.025 0.0375 0.05 0.0625 0.075 0.0875 0.1 0.1125 0.125 0.1375 0.15 0.1625 0.175 0.1875 0.2 0.2125 0.225 0.2375 0.25 0.2625 0.275 0.2875 0.3 0.3125 0.325 0.3375 0.35 0.3625 0.375 0.3875 0.4 0.4125 0.425 0.4375 0.45 0.4625 0.475 0.4875 0.5 0.5125 0.525 0.5375 0.55 0.5625 0.575 0.5875 0.6 0.6125 0.625 0.6375 0.65 0.6625 0.675 0.6875 0.7 0.7125 0.725 0.7375 0.75 0.7625 0.775 0.7875 0.8 0.8125 0.825 0.8375 0.85 0.8625 0.875 0.8875 0.9 0.9125 0.925 0.9375 0.95 0.9625 0.975 0.9875 1];
            app.nextbut = uibutton(app.UIFigure, 'push');
            app.nextbut.ButtonPushedFcn = createCallbackFcn(app, @nextbutButtonPushed, true);
            app.nextbut.Text = 'Next';
            app.prevbut = uibutton(app.UIFigure, 'push');
            app.prevbut.ButtonPushedFcn = createCallbackFcn(app, @prevbutButtonPushed, true);
            app.prevbut.Text = 'Previous';
            app.th = uieditfield(app.UIFigure, 'text');
            app.th.Value = 'Edit Text';
            app.alldone = uilabel(app.UIFigure);
            app.alldone.Text = 'All done';
            app.imnum = uilabel(app.UIFigure);
            app.imnum.Text = 'Static Text';
            app.text6 = uilabel(app.UIFigure);
            app.text6.Text = 'If all images are similar';
            app.text7 = uilabel(app.UIFigure);
            app.text7.Text = 'If images all need a different threshold';
            app.finish = uibutton(app.UIFigure, 'push');
            app.finish.ButtonPushedFcn = createCallbackFcn(app, @finishButtonPushed, true);
            app.finish.Text = 'Done';
            app.th_type = uidropdown(app.UIFigure);
            app.th_type.Items = {'Gray Threshold', 'Variance Threshold'};
            app.th_type.ItemsData = [1 0];
            app.th_type.ValueChangedFcn = createCallbackFcn(app, @th_typeValueChanged, true);
            app.th_type.Value = 1;
            app.auto_or_not = uidropdown(app.UIFigure);
            app.auto_or_not.Items = {'Manual', 'Auto'};
            app.auto_or_not.ItemsData = [0 1];
            app.auto_or_not.ValueChangedFcn = createCallbackFcn(app, @auto_or_notValueChanged, true);
            app.auto_or_not.Value = 1;
            app.invert_mask = uicheckbox(app.UIFigure);
            app.invert_mask.ValueChangedFcn = createCallbackFcn(app, @invert_maskValueChanged, true);
            app.invert_mask.Text = 'Invert Mask';
            app.fsize = uieditfield(app.UIFigure, 'text');
            app.fsize.ValueChangedFcn = createCallbackFcn(app, @fsizeValueChanged, true);
            app.fsize.Value = '3';
            app.text_fsize = uilabel(app.UIFigure);
            app.text_fsize.Text = 'Filter size';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUImask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end