classdef GUIparam_working < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AutoCheckBox           matlab.ui.control.CheckBox
        imnum                  matlab.ui.control.Label
        apply                  matlab.ui.control.Button
        mdisp                  matlab.ui.control.EditField
        text40                 matlab.ui.control.Label
        pushbutton_OK          matlab.ui.control.Button
        text_comptime          matlab.ui.control.Label
        text37                 matlab.ui.control.Label
        text_curdir            matlab.ui.control.Label
        pushbutton_testklt     matlab.ui.control.Button
        text_npoints           matlab.ui.control.Label
        text33                 matlab.ui.control.Label
        pushbutton_testpoints  matlab.ui.control.Button
        uipanel2               matlab.ui.container.Panel
        edit_method            matlab.ui.control.EditField
        text39                 matlab.ui.control.Label
        edit_threshold         matlab.ui.control.EditField
        edit_maxiteration      matlab.ui.control.EditField
        edit_removedrift       matlab.ui.control.EditField
        edit_dt                matlab.ui.control.EditField
        edit_winsize           matlab.ui.control.EditField
        edit_pyramidlevel      matlab.ui.control.EditField
        text13                 matlab.ui.control.Label
        text12                 matlab.ui.control.Label
        text11                 matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        text9                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        uipanel1               matlab.ui.container.Panel
        edit_mask              matlab.ui.control.EditField
        edit_mindist           matlab.ui.control.EditField
        text4                  matlab.ui.control.Label
        text2                  matlab.ui.control.Label
        axes_vectors           matlab.ui.control.UIAxes
        axes_points            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        curdir % Description
        savedir
        list
        num
        FileName
        savename
        param
        param_init
        results_pathname
        im1
        im2
        t1
        t2
        pts
        all_local_coverage
        global_coverage
        pyr1
        pyr2
        sp
        err
        conver
        med
    end

    methods (Access = private)
        
        function app = compute_local_coverage(app, pts)
            % This function, given a set of points on the spheroid image,
            % and the correspoonding mask, calculates the average number of
            % pixels associated to the points, either over the whole
            % spheroid or in 20x20 boxes around each point

                load([app.savedir app.savename '_mask.mat'])
                [rows, cols] = find(mask);
                r0 = min(rows);
                r1 = max(rows);
                c0 = min(cols);
                c1 = max(cols);
            
                sub_division_dim_pix = 20; %size of subtiles
                num_tile_row = round(floor((r1 - r0) ./ sub_division_dim_pix));
                num_tile_col = round(floor((c1 - c0) ./ sub_division_dim_pix));
            
                marker_row = pts(:,2);
                marker_col = pts(:,1);
            
                clear all_local_coverage
                row_index = 0;
                for rr=0:num_tile_row-1
                    for cc=0:num_tile_col-1
                        r_moins = r0 + rr * sub_division_dim_pix;
                        r_plus = r0 + (rr+1) * sub_division_dim_pix;
                        c_moins = c0 + cc * sub_division_dim_pix;
                        c_plus = c0 + (cc+1) * sub_division_dim_pix;
                        % tile_box = [[r_moins, c_moins],[r_moins, c_plus],[r_plus, c_plus],[r_plus, c_moins]];
                        div_mask = sum(mask(r_moins:r_plus,c_moins:c_plus), 'all');
                        %
                        if div_mask~=0
                            num_div_markers = sum((r_moins<marker_row)&(r_plus>marker_row)&(c_moins<marker_col)&(c_plus>marker_col));
                            if num_div_markers ~= 0
                                local_coverage = div_mask/num_div_markers;
                                row_index = row_index + 1;
                                app.all_local_coverage(row_index)=local_coverage;
                            end
                        end
                    end
                end
                    app.global_coverage = sum(mask,'all')./length(marker_row);
                    app.all_local_coverage = sort(app.all_local_coverage, 'ascend');
        end
        
        function app = findpoints(app)
            % This function finds local intensity maxima aver the image,
            % and keeps those which fall within a binary mask, if given
            
            features = 10000000;
            [ptst(:,1), ptst(:,2)] = localMaximum_h(app.im1, app.param.minDist, app.param.excludeEqualPoints, features);
            if app.param.mask == 1
                load([app.savedir app.savename '_mask.mat']);
                ind = sub2ind(size(mask), ptst(:,1), ptst(:,2));
                ind2 = mask(ind)>0;
                pts(:,:) = ptst(ind2,:);
                % pts(:,:) = ptst;
            else
                pts(:,:)=ptst(:,:);
            end

            app.text_npoints.Text = num2str(length(pts));
            app.pts=pts;
            
            %%%%%%%%%

            imshow(app.im1,[],'parent',app.axes_points);
            hold(app.axes_points,'on');
            plot(app.pts(:,2), app.pts(:,1),'.r','parent',app.axes_points);
            hold(app.axes_points,'off');
        end
        
        function app = klt(app)
            %This function executes a klt sample of the movie

            WindowSize = repmat(app.param.winSize,app.param.pyramidLevel,1);
            MaxIteration = repmat(app.param.maxIteration,1,app.param.pyramidLevel);
            Threshold = repmat(app.param.threshold,1,app.param.pyramidLevel);
            
            if app.param.gpu==0
            [app.sp, conver, app.err] = pyrLK_2D(app.pyr1,app.pyr2,app.pts,WindowSize,MaxIteration,Threshold);
            else
                [app.sp, conver, app.err] = pyrLK_2Dgpu(app.pyr1,app.pyr2,app.pts,WindowSize,MaxIteration,Threshold);
            end
            app.conver = prod(conver,2)>0;
            %sum(app.conver)
            app.med=nanmedian(sqrt(app.sp(app.conver,1).^2+app.sp(app.conver,2).^2));
            
            
            if app.AutoCheckBox.Value == 1
                WS_it = 0;
                while WS_it < 15
                    if app.param.gpu==0
                        [app.sp, conver, app.err] = pyrLK_2D(app.pyr1,app.pyr2,app.pts,WindowSize,MaxIteration,Threshold);
                    else
                        [app.sp, conver, app.err] = pyrLK_2Dgpu(app.pyr1,app.pyr2,app.pts,WindowSize,MaxIteration,Threshold);
                    end

                    if max(sqrt(app.sp(app.conver,1).^2+app.sp(app.conver,2).^2))>WindowSize
                        WindowSize = WindowSize +2;
                        WS_it = 0;
                    else
                        WS_it = WS_it+1;
                    end
                    
                end
                app.param.winSize = WindowSize(1);
                app.edit_winsize.Value = num2str(WindowSize(1));
            end
            
            %%%%%%%%%%
            imshow(app.im1,[],'parent',app.axes_vectors);
            hold(app.axes_vectors,'on');
            %quiver(app.pts(:, 2), app.pts(:,1), app.sp(:,2), app.sp(:,1), 5, 'Color', [1 0 0], 'LineWidth', 1, 'parent', app.axes_vectors);
            quiver(app.pts(app.conver, 2), app.pts(app.conver,1), app.sp(app.conver,2), app.sp(app.conver,1), 5, 'Color', [0.1 0.8 0.1], 'parent', app.axes_vectors);
            text(10, 24, [num2str(app.t1) ' - ' num2str(app.t2)],'Color','w','FontSize',14); drawnow;
            hold(app.axes_vectors,'off');
            %%%%%%%%%%

            if app.param.removedrift == 1
                app.sp(:,2) = app.sp(:,2) - mean(app.sp(:,2));
                app.sp(:,1) = app.sp(:,1) - mean(app.sp(:,1));
                %%%%%%%%%%
                hold(app.axes_vectors,'on');
                quiver(app.pts(app.conver,2),app.pts(app.conver,1),app.sp(app.conver,2),app.sp(app.conver,1),5,'m','parent',app.axes_vectors);
                hold(app.axes_vectors,'off');
                %%%%%%%%%%
            end
        end
        
        function app = loadfiles(app)
            %%
            %%%%%%%%%% MY FUNCTIONS %%%%%%%%%%
            
            if app.param.stack
                app.savename =  app.list{app.num}(1:end-4);
                pos = strfind(app.curdir, filesep);
                app.text_curdir.Text = num2str(app.curdir(pos(end-1)+1:end-1));
                app.imnum.Text = [num2str(app.num) '/' num2str(length(app.list))];
            else
            
            fl = regexp(app.curdir(1:end-1),filesep);
            app.savename = app.curdir(fl(end)+1:end-1);
            app.text_curdir.Text = num2str(app.curdir);
            
            end
        end

        function app = loadparams(app)
            if (app.AutoCheckBox.Value == 1) | (app.num==1)
                app.param = app.param_init;
                app.edit_mindist.Value = num2str(app.param_init.minDist);
                app.edit_mask.Value = num2str(app.param_init.mask);
                app.edit_pyramidlevel.Value = num2str(app.param_init.pyramidLevel);
                app.edit_winsize.Value = num2str(app.param_init.winSize);
                app.edit_dt.Value = num2str(app.param_init.dt);
                app.edit_method.Value = num2str(app.param_init.method);
                app.edit_removedrift.Value = num2str(app.param_init.removedrift);
                app.edit_maxiteration.Value = num2str(app.param_init.maxIteration);
                app.edit_threshold.Value = num2str(app.param_init.threshold);
            else
                app.param = app.param_init;
            end
        end
        
        function app = loadimages(app)
            if app.param.stack
                filename = [app.curdir app.list{app.num}];
                im1 = double(imread(filename,app.t1));
                im1=im1(2:end,1:end);
                im2 = double(imread(filename,app.t2));
                im2=im2(2:end,:);
            else
            filename = [app.curdir app.list{app.t1}];
            im1 = double(imread(filename));
            im1=im1(2:end,1:end);

            im2 = double(imread(filename));
            im2=im2(2:end,:);
            end
            im1(:,:) = im1(:,:) - mean2(im1(:,:));
            app.im1 = im1;

            im2(:,:) = im2(:,:) - mean2(im2(:,:));
            app.im2 = im2;

            imshow(app.im1,[],'parent',app.axes_points);
        end
        
        function app = makepyramid(app)
            WindowSize = repmat(app.param.winSize,app.param.pyramidLevel,1);
            app.pyr1 = makePyramid_2D(app.im1,app.param.pyramidLevel,app.param.blurRadius,WindowSize);
            app.pyr2 = makePyramid_2D(app.im2,app.param.pyramidLevel,app.param.blurRadius,WindowSize);
        end
        
        function app = outputdata(app)
            app.results(app.i).pts1 = app.pts;
            app.results(app.i).pts2 = app.pts + app.sp;
            app.results(app.i).sp = app.sp;
            app.results(app.i).err = app.err;
            app.results(app.i).conver = app.conver;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
             prm.minDist=20;
            prm.minfeatures=1000;
            prm.mask=0;
            prm.pyramidLevel=3;
            prm.winSize=8;
            prm.dt=1;
            prm.method=1;
            prm.removedrift=0;
            prm.maxIteration=20;
            prm.threshold=0.1;
            
            p = inputParser;
            addRequired(p, 'Directory', @isstr);
            addParameter(p, 'SaveDir', pwd, @isstr);
            addParameter(p, 'Parameters', prm, @isstruct);
            addParameter(p, 'num', 1);
            
            parse(p, varargin{:});
            
            app.curdir = p.Results.Directory;
            app.savedir = p.Results.SaveDir;
            app.param_init = p.Results.Parameters;
                        % other
            app.param_init.excludeEqualPoints = 1;
            app.param_init.blurRadius = -1;


            app.list = listTiffs(app.curdir);
            app.num=p.Results.num;
            app = loadparams(app);
            app.t1 = 1;
            app.t2 = 1 + app.param.dt;
            app = loadfiles(app);   

            % app.applyButtonPushed
        end

        % Value changed function: edit_mindist
        function edit_mindistValueChanged(app, event)
            value = app.edit_mindist.Value;
            app.param.minDist = str2double(value);
        end

        % Callback function
        function edit_minfeaturesValueChanged(app, event)
            value = app.edit_minfeatures.Value;
            app.param.minfeatures = str2double(value);
        end

        % Value changed function: edit_mask
        function edit_maskValueChanged(app, event)
            value = app.edit_mask.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.mask = str2double(value);
            end
        end

        % Button pushed function: pushbutton_testpoints
        function pushbutton_testpointsButtonPushed(app, event)
            app = loadimages(app);
            app = findpoints(app);
            % if app.AutoCheckBox.Value == 1
            %     iter = 0;
            %     min_coverage = app.param.coverage;
            %     app=compute_local_coverage(app, app.pts);
            %     while min(app.all_local_coverage(1:3)) <= min_coverage
            %         iter = iter+1;
            %         if iter==2
            %             app.param.minDist= app.param.minDist + 1;
            %             iter=0;
            %         end
            %         app = findpoints(app);
            %         app=compute_local_coverage(app, app.pts);
            %     end
            %     app.edit_mindist.Value = num2str(app.param.minDist); 
            % 
            % end
        end

        % Value changed function: edit_pyramidlevel
        function edit_pyramidlevelValueChanged(app, event)
            value = app.edit_pyramidlevel.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.pyramidLevel = input;
            end
        end

        % Value changed function: edit_winsize
        function edit_winsizeValueChanged(app, event)
            value = app.edit_winsize.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.winSize = input;
            end
        end

        % Value changed function: edit_dt
        function edit_dtValueChanged(app, event)
            value = app.edit_dt.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.dt = input;
            end
        end

        % Value changed function: edit_method
        function edit_methodValueChanged(app, event)
            value = app.edit_method.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.removedrift = input;
            end
        end

        % Value changed function: edit_removedrift
        function edit_removedriftValueChanged(app, event)
            value = app.edit_removedrift.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.dt = input;
            end
        end

        % Value changed function: edit_maxiteration
        function edit_maxiterationValueChanged(app, event)
            value = app.edit_maxiteration.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.maxIteration = input;
            end
        end

        % Value changed function: edit_threshold
        function edit_thresholdValueChanged(app, event)
            value = app.edit_threshold.Value;
            input = str2double(value);
            if isnan(input)
                errordlg('You must enter a numeric value','Invalid Input','modal')
                return
            else
                app.param.threshold = input;
            end
        end

        % Button pushed function: pushbutton_testklt
        function pushbutton_testkltButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            tic

            app.pushbutton_testpointsButtonPushed
            app = makepyramid(app);
            app = klt(app);
            
            app.text_comptime.Text = num2str(toc);
            app.mdisp.Value = num2str(app.med);
        end

        % Button pushed function: pushbutton_OK
        function pushbutton_OKButtonPushed(app, event)
            paramf = app.param;
            save([app.savedir,app.savename,'_param.mat'],'paramf','-v7.3','-nocompression')
            
            if app.num==length(app.list)
                delete(app.UIFigure);
            else
                app.num =mod(app.num, length(app.list))+1;
                app = loadfiles(app);
                app = loadimages(app);
                app  = loadparams(app);
                app.pushbutton_testkltButtonPushed
            end
        end

        % Button pushed function: apply
        function applyButtonPushed(app, event)
            for f= app.num:length(app.list)
                app.num = f;
                app = loadfiles(app);
                app = loadimages(app);
                app  = loadparams(app);
                app.pushbutton_testkltButtonPushed
                exportapp(app.UIFigure, [app.savedir app.savename '_param.png']);
                paramf = app.param;
                save([app.savedir,app.savename,'_param.mat'],'paramf','-v7.3','-nocompression')
            end
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_points = uiaxes(app.UIFigure);
            app.axes_points.XTick = [];
            app.axes_points.YTick = [];
            app.axes_vectors = uiaxes(app.UIFigure);
            app.axes_vectors.XTick = [];
            app.axes_vectors.YTick = [];
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Find Points';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Min Distance';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Mask';
            app.edit_mindist = uieditfield(app.uipanel1, 'text');
            app.edit_mindist.ValueChangedFcn = createCallbackFcn(app, @edit_mindistValueChanged, true);
            app.edit_mindist.Value = 'Edit Text';
            app.edit_mask = uieditfield(app.uipanel1, 'text');
            app.edit_mask.ValueChangedFcn = createCallbackFcn(app, @edit_maskValueChanged, true);
            app.edit_mask.Value = 'Edit Text';
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'KLT';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = 'Pyramid Level';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Text = 'Window Size';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Delta t';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Text = 'Threshold';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = 'Iterations';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Text = 'Remove Drift';
            app.edit_pyramidlevel = uieditfield(app.uipanel2, 'text');
            app.edit_pyramidlevel.ValueChangedFcn = createCallbackFcn(app, @edit_pyramidlevelValueChanged, true);
            app.edit_pyramidlevel.Value = 'Edit Text';
            app.edit_winsize = uieditfield(app.uipanel2, 'text');
            app.edit_winsize.ValueChangedFcn = createCallbackFcn(app, @edit_winsizeValueChanged, true);
            app.edit_winsize.Value = 'Edit Text';
            app.edit_dt = uieditfield(app.uipanel2, 'text');
            app.edit_dt.ValueChangedFcn = createCallbackFcn(app, @edit_dtValueChanged, true);
            app.edit_dt.Value = 'Edit Text';
            app.edit_removedrift = uieditfield(app.uipanel2, 'text');
            app.edit_removedrift.ValueChangedFcn = createCallbackFcn(app, @edit_removedriftValueChanged, true);
            app.edit_removedrift.Value = 'Edit Text';
            app.edit_maxiteration = uieditfield(app.uipanel2, 'text');
            app.edit_maxiteration.ValueChangedFcn = createCallbackFcn(app, @edit_maxiterationValueChanged, true);
            app.edit_maxiteration.Value = 'Edit Text';
            app.edit_threshold = uieditfield(app.uipanel2, 'text');
            app.edit_threshold.ValueChangedFcn = createCallbackFcn(app, @edit_thresholdValueChanged, true);
            app.edit_threshold.Value = 'Edit Text';
            app.text39 = uilabel(app.uipanel2);
            app.text39.Text = 'Method';
            app.edit_method = uieditfield(app.uipanel2, 'text');
            app.edit_method.ValueChangedFcn = createCallbackFcn(app, @edit_methodValueChanged, true);
            app.edit_method.Value = 'Edit Text';
            app.pushbutton_testpoints = uibutton(app.UIFigure, 'push');
            app.pushbutton_testpoints.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_testpointsButtonPushed, true);
            app.pushbutton_testpoints.Text = 'find points';
            app.text33 = uilabel(app.UIFigure);
            app.text33.Text = 'number of points found:';
            app.text_npoints = uilabel(app.UIFigure);
            app.text_npoints.Text = '';
            app.pushbutton_testklt = uibutton(app.UIFigure, 'push');
            app.pushbutton_testklt.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_testkltButtonPushed, true);
            app.pushbutton_testklt.Text = 'test klt';
            app.text_curdir = uilabel(app.UIFigure);
            app.text_curdir.Text = 'Static Text';
            app.text37 = uilabel(app.UIFigure);
            app.text37.Text = 'computation time 1 step:';
            app.text_comptime = uilabel(app.UIFigure);
            app.text_comptime.Text = '';
            app.pushbutton_OK = uibutton(app.UIFigure, 'push');
            app.pushbutton_OK.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_OKButtonPushed, true);
            app.pushbutton_OK.Text = 'OK';
            app.text40 = uilabel(app.UIFigure);
            app.text40.Text = 'median displacement:';
            app.mdisp = uieditfield(app.UIFigure, 'text');
            app.apply = uibutton(app.UIFigure, 'push');
            app.apply.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.apply.Text = 'Apply to all';
            app.imnum = uilabel(app.UIFigure);
            app.imnum.Text = 'Static Text';
            app.AutoCheckBox = uicheckbox(app.UIFigure);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIparam_working(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end